classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        WczytajplikButton      matlab.ui.control.Button
        RozpoznanaliteraLabel  matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        RozpoznajButton        matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
        WyczyButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        File_Name char
        Path_Name char
    end
    

    methods (Access = private)

        % Button pushed function: WczytajplikButton
        function WczytajplikButtonPushed(app, event)
            [app.File_Name, app.Path_Name] = uigetfile('*.png', 'D:\politechnika\semestr_8\inzynierka\siec');
            %imshow([app.Path_Name,app.File_Name]);
            if ( ~isempty(app.File_Name) && ~isempty(app.Path_Name))
                app.EditField.Value = strcat(app.Path_Name,app.File_Name);
                app.TextArea.Value = '';
                app.RozpoznajButton.Enable = 'on';
                app.WyczyButton.Enable = 'on';
            end
        end

        % Button pushed function: RozpoznajButton
        function RozpoznajButtonPushed(app, event)
            img = imread([app.Path_Name,app.File_Name]);
            app.TextArea.Value = getLetter(img);
        end

        % Button pushed function: WyczyButton
        function WyczyButtonValueChanged(app, event)
            app.RozpoznajButton.Enable = 'off';
            app.WyczyButton.Enable = 'off';
            app.EditField.Value = '';
            app.TextArea.Value = '';
            app.File_Name = '';
            app.Path_Name = '';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WczytajplikButton = uibutton(app.UIFigure, 'push');
            app.WczytajplikButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajplikButtonPushed, true);
            app.WczytajplikButton.Text = 'Wczytaj plik';
            app.RozpoznanaliteraLabel = uilabel(app.UIFigure);
            app.RozpoznanaliteraLabel.Text = 'Rozpoznana litera';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.RozpoznajButton = uibutton(app.UIFigure, 'push');
            app.RozpoznajButton.ButtonPushedFcn = createCallbackFcn(app, @RozpoznajButtonPushed, true);
            app.RozpoznajButton.Enable = 'off';
            app.RozpoznajButton.Text = 'Rozpoznaj';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.WyczyButton = uibutton(app.UIFigure, 'push');
            app.WyczyButton.ButtonPushedFcn = createCallbackFcn(app, @WyczyButtonValueChanged, true);
            app.WyczyButton.Enable = 'off';
            app.WyczyButton.Text = 'Wyczyść';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end